classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        sonucLabel           matlab.ui.control.Label
        SonuLabel            matlab.ui.control.Label
        IslemeButton         matlab.ui.control.Button
        yksekDropDown        matlab.ui.control.DropDown
        yksekDropDownLabel   matlab.ui.control.Label
        optimiDropDown       matlab.ui.control.DropDown
        optimiDropDownLabel  matlab.ui.control.Label
        ADropDown            matlab.ui.control.DropDown
        ADropDownLabel       matlab.ui.control.Label
        FotoYukleButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        seciliImage = [];
        seciliAg = "myCnn";
        seciliOptimizasyon = "Adam";
        seciliYuksek = "Yuksek";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FotoYukleButton
        function FotoYukleBtnPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           imagesc(app.UIAxes, ImageFile);
           app.seciliImage = ImageFile;
        end

        % Value changed function: ADropDown
        function ADropDownValueChanged(app, event)
            value = app.ADropDown.Value;
            if isequal(value,"GoogleNet")
                app.seciliAg = "google";
            elseif isequal(value,"MobileNetV2")
                app.seciliAg = "mobile";
            elseif isequal(value, "MyCNN")
                app.seciliAg = "myCnn";
            elseif isequal(value, "AlexNet")
                app.seciliAg = "alex";
            else
                app.seciliAg = "resnet";
            end
        end

        % Value changed function: optimiDropDown
        function optimiDropDownValueChanged(app, event)
            value = app.optimiDropDown.Value;
            if isequal(value, "ADAM")
                app.seciliOptimizasyon = "Adam";
            elseif isequal(value, "SGDM")
                app.seciliOptimizasyon = "Sgdm";
            elseif isequal(value, "RMSPROP")
                app.seciliOptimizasyon = "Rmsprop";
            end 
        end

        % Value changed function: yksekDropDown
        function yksekDropDownValueChanged(app, event)
            value = app.yksekDropDown.Value; 
            if isequal(value, "Yüksek Ayar")
                app.seciliOptimizasyon = "Yuksek";
            else 
                app.seciliOptimizasyon = "Dusuk";
            end
        end

        % Button pushed function: IslemeButton
        function IslemeButtonPushed(app, event)
            matFilePath = app.seciliAg + app.seciliOptimizasyon + app.seciliYuksek + ".mat"; 
            loadedData = load(matFilePath);
            resizedSeciliImage = imresize(app.seciliImage,[224 224]);
            sonuc = classify(loadedData.Ag,resizedSeciliImage);
            app.sonucLabel.Text = sonuc; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FotoYukleButton = uibutton(app.UIFigure, 'push');
            app.FotoYukleButton.ButtonPushedFcn = createCallbackFcn(app, @FotoYukleBtnPushed, true);
            app.ADropDownLabel = uilabel(app.UIFigure);
            app.ADropDownLabel.Text = 'Ağı';
            app.ADropDown = uidropdown(app.UIFigure);
            app.ADropDown.Items = {'AlexNet', 'GoogleNet', 'MyCNN', 'MobileNetV2', 'ResNet'};
            app.ADropDown.ValueChangedFcn = createCallbackFcn(app, @ADropDownValueChanged, true);
            app.ADropDown.Value = 'MyCNN';
            app.optimiDropDownLabel = uilabel(app.UIFigure);
            app.optimiDropDownLabel.Text = 'optimi';
            app.optimiDropDown = uidropdown(app.UIFigure);
            app.optimiDropDown.Items = {'ADAM', 'SGDM', 'RMSPROP'};
            app.optimiDropDown.ValueChangedFcn = createCallbackFcn(app, @optimiDropDownValueChanged, true);
            app.optimiDropDown.Value = 'ADAM';
            app.yksekDropDownLabel = uilabel(app.UIFigure);
            app.yksekDropDownLabel.Text = 'yüksek';
            app.yksekDropDown = uidropdown(app.UIFigure);
            app.yksekDropDown.Items = {'Yüksek Ayar', 'Düşük Ayar'};
            app.yksekDropDown.ValueChangedFcn = createCallbackFcn(app, @yksekDropDownValueChanged, true);
            app.yksekDropDown.Value = 'Yüksek Ayar';
            app.IslemeButton = uibutton(app.UIFigure, 'push');
            app.IslemeButton.ButtonPushedFcn = createCallbackFcn(app, @IslemeButtonPushed, true);
            app.IslemeButton.Text = 'Button2';
            app.SonuLabel = uilabel(app.UIFigure);
            app.SonuLabel.Text = 'Sonuç: ';
            app.sonucLabel = uilabel(app.UIFigure);
            app.sonucLabel.Text = 'bekleniyor...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end